classdef ui_holo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        App_uifigure                   matlab.ui.Figure
        minmaxnormvideoCheckBox        matlab.ui.control.CheckBox
        arrow_left_add_bboxes          matlab.ui.control.Button
        COPYALLBBOXESPanel             matlab.ui.container.Panel
        arrow_right_add_bboxes         matlab.ui.control.Button
        deleteallbboxesinvideoButton   matlab.ui.control.Button
        deleteallbboxesinframeButton   matlab.ui.control.Button
        NovideofoundLabel              matlab.ui.control.Label
        suggestionresultTextArea       matlab.ui.control.TextArea
        suggestionresultTextAreaLabel  matlab.ui.control.Label
        SuggestBBoxButton              matlab.ui.control.Button
        minmaxnormframeCheckBox        matlab.ui.control.CheckBox
        videoplayerPanel               matlab.ui.container.Panel
        framerateEditField             matlab.ui.control.NumericEditField
        framerateEditFieldLabel        matlab.ui.control.Label
        stop_button                    matlab.ui.control.Button
        play_button                    matlab.ui.control.Button
        frameSpinner                   matlab.ui.control.Spinner
        frameSpinnerLabel              matlab.ui.control.Label
        newlabel_button                matlab.ui.control.StateButton
        arrow_right                    matlab.ui.control.Button
        arrow_left                     matlab.ui.control.Button
        hologramvideosListBox          matlab.ui.control.ListBox
        hologramvideosListBoxLabel     matlab.ui.control.Label
        selectvideosButton             matlab.ui.control.Button
        ax_holofr                      matlab.ui.control.UIAxes
    end

    properties (Access=private)
        vid_file_names
        app_path
        vid_folders_struct
        curr_vid_obj
        holo_vids_holder_obj
        holo_vids_holder_array
        current_vid_files_path
        just_past_fr
        timer_vid_player
        pause_btwn_fr
        ctrl_pressed
        copy_bboxes_to_next_fr
        copy_bboxes_to_prev_fr
    end

    methods (Access = private)
        function init_vid_player_timer(app)
            app.timer_vid_player = timer();
            app.timer_vid_player.TimerFcn = @(x,y)run_video(app);
            %             matlab.internal.timer.ExecutionModeEnum.fixedRate
            app.timer_vid_player.ExecutionMode = 'fixedRate';
            stop(app.timer_vid_player);
            app.timer_vid_player.BusyMode = 'queue';
            app.timer_vid_player.Period = app.pause_btwn_fr;
            app.timer_vid_player.Name = 'vid_player';

            %             'TimerFcn', @(true)appr.timer_run_video(app), 'ExecutionMode', 'fixedRate', 'Period', app.pause_btwn_fr, 'Name', 'vid_player');
        end
        %         function detect_bbox_moving(app)
        %             app.curr_vid_obj.change_bbox_color();
        %         end
        function convert_fr_rate_to_ms(app)
            app.pause_btwn_fr = double(uint16(app.pause_btwn_fr*1000))/1000;
        end
        function run_video(app)
            app.arrow_rightButtonPushed(true);
        end
        function get_app_path(app)
            app_path_dummy = mfilename('fullpath');

            slash = '\\';
            if isunix()
                slash = '/';
            end

            app.app_path = regexprep(app_path_dummy, [slash 'ui_holo.*'],'');

            cd(app.app_path);
        end
        function init_path_is_set = find_init_vid_files_path(app)
            f_names = [];
            app.current_vid_files_path = '';
            idx = [];
            vid_files_NOT_found = true;

            if exist('_videos','dir') == 7 %tries first in default directory
                app.current_vid_files_path = '_videos';
                vid_files_NOT_found = app.get_vid_names_in_current_vid_files_path();
            end

            if vid_files_NOT_found %tries then in stored path from the app's last run
                if exist('curr_vids_path.mat','file') == 2
                    load('curr_vids_path.mat');
                    app.current_vid_files_path = curr_vids_path;
                    vid_files_NOT_found = app.get_vid_names_in_current_vid_files_path();
                end
            end

            init_path_is_set = ~vid_files_NOT_found;
        end
        function vid_files_NOT_found = get_vid_names_in_current_vid_files_path(app)
            dir_data = dir(app.current_vid_files_path);
            f_names = {dir_data(:).name};
            idx = find(contains(f_names, '.avi'));
            vid_files_NOT_found = isempty(idx);

            if vid_files_NOT_found
                app.vid_file_names = {};
                app.current_vid_files_path = '';
            else
                app.vid_file_names = f_names(idx);
            end
        end
        function update_last_fr_no(app)
            app.just_past_fr = fr;
        end

        function update_video_list_box(app)
            % vid_file_dir = dir(app.current_vid_files_path);
            % vid_names = {vid_file_dir(:).name};
            % idx = find(contains(vid_names,'.avi'));

            if isempty(app.vid_file_names)
                app.hologramvideosListBox.Items = {};
                app.current_vid_files_path = '';
                warndlg('No videos found.');
            else
                if ischar(app.vid_file_names)
                    app.vid_file_names = {app.vid_file_names};
                end
                app.hologramvideosListBox.Items = app.vid_file_names;
                app.hologramvideosListBox.Value  = app.hologramvideosListBox.Items{1};
                app.hologramvideosListBoxValueChanged(true);
            end
            app.update_folders_seen_struct();
        end

        function update_folders_seen_struct(app)
            dummy_vid_folders_struct.files_path = app.current_vid_files_path;
            dummy_vid_folders_struct.file_names = app.hologramvideosListBox.Items;

            if isempty(app.vid_folders_struct)
                app.vid_folders_struct = dummy_vid_folders_struct;
            else
                app.vid_folders_struct(end+1) = dummy_vid_folders_struct;
            end
        end

        function ret = video_has_been_loaded(app)
            ret = ~isempty(app.curr_vid_obj) && isvalid(app.curr_vid_obj) ...
                && isa(app.curr_vid_obj,'HoloVid');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.get_app_path();
            add_app_folders_to_path();

            app.ax_holofr.Visible = 'on';
            app.ax_holofr.Colormap = gray(256);
            axis(app.ax_holofr, 'image');
            app.framerateEditField.Value = 5;

            app.pause_btwn_fr = 1/app.framerateEditField.Value;
            app.frameSpinner.Step = 1;
            app.init_vid_player_timer();

            app.curr_vid_obj = [];
            app.copy_bboxes_to_next_fr = false;
            app.copy_bboxes_to_prev_fr = false;
            app.vid_file_names = {};
            app.ctrl_pressed = false;
            init_path_is_set = app.find_init_vid_files_path();


            if init_path_is_set
                app.holo_vids_holder_obj = HoloVidsHolder(app.app_path, app.ax_holofr, app.current_vid_files_path);
                app.update_video_list_box();
            else
                app.holo_vids_holder_obj = HoloVidsHolder(app.app_path, app.ax_holofr);
            end
        end

        % Button pushed function: selectvideosButton
        function selectvideosButtonPushed(app, event)
            %             app.current_vid_files_path = uigetdir();

            [app.vid_file_names, app.current_vid_files_path] = uigetfile('*.avi','Select One or More Files','MultiSelect','on');
            if isnumeric(app.current_vid_files_path) || isempty(app.current_vid_files_path) %not zero nor empty
                app.current_vid_files_path = '';
                app.vid_file_names = {};
            else
                if app.holo_vids_holder_obj.vid_files_path_is_NOT_set()
                    app.holo_vids_holder_obj.set_vid_files_path(app.current_vid_files_path);
                else
                    if app.video_has_been_loaded() %chaging folders, storing objects related to last seen videos so it can be loaded next if needed
                        cd(app.app_path);
                        app.holo_vids_holder_obj.add_holo_vid_obj_to_map(app.curr_vid_obj);
                        app.holo_vids_holder_obj.save_labels();
                    end
                    if isempty(app.holo_vids_holder_array) %adds to the array
                        app.holo_vids_holder_array = app.holo_vids_holder_obj.copy;
                    else
                        app.holo_vids_holder_array(end+1) = app.holo_vids_holder_obj.copy;
                    end
                    app.holo_vids_holder_obj = HoloVidsHolder(app.app_path, app.ax_holofr, app.current_vid_files_path); %creates new obj

                end

                addpath(app.current_vid_files_path);
                app.update_video_list_box();
            end
        end

        % Value changed function: hologramvideosListBox
        function hologramvideosListBoxValueChanged(app, event)

            if app.video_has_been_loaded() %store remaning labels from previous video
                app.holo_vids_holder_obj.add_holo_vid_obj_to_map(app.curr_vid_obj);
                %STORE VIDEO OBJ INTO MAP HERE
            end
            vid_name_no_ext = app.hologramvideosListBox.Value;
            vid_name_no_ext = vid_name_no_ext(1:end-4);

            if app.holo_vids_holder_obj.vid_is_in_map(vid_name_no_ext)
                app.curr_vid_obj = app.holo_vids_holder_obj.get_vid_obj_from_map(vid_name_no_ext);
            else
                file_path = [app.current_vid_files_path '\' app.hologramvideosListBox.Value];
                if isunix()
                    file_path = regexprep(file_path,'\\','/');
                end
                app.curr_vid_obj = HoloVid(file_path, app.ax_holofr, app.minmaxnormframeCheckBox.Value);
            end
            app.framerateEditField.Value = app.curr_vid_obj.get_fr_rate();
            app.framerateEditFieldValueChanged(true);
            app.frameSpinner.Limits(2) = app.curr_vid_obj.no_of_frames;
            app.frameSpinner.Value = app.curr_vid_obj.selected_fr_no;
            %             app.minmaxnormframeCheckBoxValueChanged(true);
            app.minmaxnormvideoCheckBoxValueChanged(true);
            app.frameSpinnerValueChanged(true);
        end

        % Button pushed function: arrow_left
        function arrow_leftButtonPushed(app, event)
            if app.video_has_been_loaded()
                app.curr_vid_obj.prev_fr(app.copy_bboxes_to_prev_fr);
                app.copy_bboxes_to_prev_fr = false;
                app.frameSpinner.Value = app.curr_vid_obj.selected_fr_no;
                app.frameSpinnerValueChanged(true);
            end
        end

        % Button pushed function: arrow_right
        function arrow_rightButtonPushed(app, event)
            if app.video_has_been_loaded()
                app.curr_vid_obj.next_fr(app.copy_bboxes_to_next_fr);
                app.copy_bboxes_to_next_fr = false;

                app.frameSpinner.Value = app.curr_vid_obj.selected_fr_no;
                app.frameSpinnerValueChanged(true);
            end
        end

        % Key press function: App_uifigure
        function App_uifigureKeyPress(app, event)
            if isa(event,'matlab.ui.eventdata.KeyData')
                key = event.Key;
                ctrl_related_key_has_NOT_been_pressed = true;
                if app.ctrl_pressed
                    if strcmpi(key, 'Z')
                        app.curr_vid_obj.reload_last_deleted_bbox();
                        ctrl_related_key_has_NOT_been_pressed = false;
                    end
                end

                if ctrl_related_key_has_NOT_been_pressed
                    app.ctrl_pressed = false;
                    if strcmpi(key, 'control')
                        app.ctrl_pressed = true;
                    elseif strcmpi(key, 'delete')
                        app.curr_vid_obj.delete_selected_bbox();
                    elseif strcmpi(key, 'n')
                        app.newlabel_buttonValueChanged(true);
                    elseif strcmpi(key,'rightarrow')
                        app.arrow_rightButtonPushed(true)
                    elseif strcmpi(key, 'leftarrow')
                        app.arrow_leftButtonPushed(true)
                    elseif strcmpi(key, 'C')
                        app.curr_vid_obj.paste_selected_bbox();
                    end
                end
            end
            pause(0.2);
        end

        % Button pushed function: play_button
        function play_buttonButtonPushed(app, event)
            if app.video_has_been_loaded()
                if strcmpi(app.timer_vid_player.Running,'off')
                    start(app.timer_vid_player);
                end
            end
        end

        % Value changed function: framerateEditField
        function framerateEditFieldValueChanged(app, event)
            value = app.framerateEditField.Value;
            vid_was_playing = false;
            if value <= 0
                app.framerateEditField.Value = 1;
            elseif value >= 20
                app.framerateEditField.Value = 20;
            end


            app.pause_btwn_fr = 1/app.framerateEditField.Value;
            app.convert_fr_rate_to_ms();

            if strcmpi(app.timer_vid_player.Running,'on')
                vid_was_playing = true;
                stop(app.timer_vid_player);
            end
            app.timer_vid_player.Period = app.pause_btwn_fr;

            if vid_was_playing
                app.play_buttonButtonPushed(true);
            end
        end

        % Button pushed function: stop_button
        function stop_buttonButtonPushed(app, event)
            if strcmpi(app.timer_vid_player.Running,'on')
                stop(app.timer_vid_player);
            end
        end

        % Value changed function: newlabel_button
        function newlabel_buttonValueChanged(app, event)
            if app.video_has_been_loaded()
                app.curr_vid_obj.add_bbox_to_selected_fr();
            end
            app.newlabel_button.Value = false;
        end

        % Button down function: ax_holofr
        function ax_holofrButtonDown(app, event)
            app.App_uifigureKeyPress(event);
        end

        % Value changed function: frameSpinner
        function frameSpinnerValueChanged(app, event)
            if app.video_has_been_loaded()
                fr = app.frameSpinner.Value;
                app.curr_vid_obj.select_fr(fr);
                txt_under_ax = {[' ' app.curr_vid_obj.vid_name]; [' frame ' num2str(app.curr_vid_obj.selected_fr_no)]};
                app.NovideofoundLabel.Text = txt_under_ax;
            end
        end

        % Value changed function: minmaxnormframeCheckBox
        function minmaxnormframeCheckBoxValueChanged(app, event)
            if app.video_has_been_loaded()
                app.curr_vid_obj.set_show_I_minmax(app.minmaxnormframeCheckBox.Value);
            end
        end

        % Callback function: SuggestBBoxButton, 
        % suggestionresultTextArea
        function suggestbboxesButtonPushed(app, event)
            if app.video_has_been_loaded()
                found_in_minmax_norm = false;
                [bboxes, scores] = suggest_bboxes(app.curr_vid_obj.get_I_orig());
                if isempty(scores)
                    [bboxes, scores] = suggest_bboxes(app.curr_vid_obj.get_I_min_max_norm());
                    if ~isempty(found_in_minmax_norm)
                        found_in_minmax_norm = true;
                    end
                end
                txt_minmax_norm = '';
                if found_in_minmax_norm
                    txt_minmax_norm = ' using min-max normalized frame image.';
                end
                txt_display_bboxes = {' suggested for ', ['frame ' num2str(app.curr_vid_obj.selected_fr_no)], txt_minmax_norm};
                if isempty(scores)
                    app.suggestionresultTextArea.Value = [{'No bounding boxes'} txt_display_bboxes];
                    app.suggestionresultTextArea.FontColor = 'r';
                    app.suggestionresultTextArea.FontWeight =  'normal';
                else
                    app.suggestionresultTextArea.Value = [{[num2str(length(scores)) ' bounding boxes']} txt_display_bboxes];
                    app.suggestionresultTextArea.FontColor = 'k';
                    app.suggestionresultTextArea.FontWeight = 'bold';
                    for i=1:length(scores)
%                         bbox = drawrectangle(app.ax_holofr, 'Position', bboxes(i,:));
                        bbox = drawrectangle(app.ax_holofr, 'Position', bboxes(i,:), 'StripeColor','r');
                        app.curr_vid_obj.add_bbox_to_selected_fr(bbox);
                    end
                end
            end
        end

        % Close request function: App_uifigure
        function App_uifigureCloseRequest(app, event)
            if app.video_has_been_loaded()
                cd(app.app_path);
%                 if app.holo_vids_holder_obj.is_empty
                app.holo_vids_holder_obj.add_holo_vid_obj_to_map(app.curr_vid_obj); 
%                 end
                if isempty(app.holo_vids_holder_array)
                    app.holo_vids_holder_array = app.holo_vids_holder_obj;
                else
                    app.holo_vids_holder_array(end+1) = app.holo_vids_holder_obj;
                end
                
                for i=1:length(app.holo_vids_holder_array)
                    hol_vid_hold_obj = app.holo_vids_holder_array(i);
                    hol_vid_hold_obj.save_labels();
                end

                curr_vids_path = app.current_vid_files_path;
                file_path_n_name = 'app_data\curr_vids_path.mat';

                if exist('app_data','dir') ~= 7
                    mkdir('app_data');
                end

                if isunix()
                    file_path_n_name = regexprep(file_path_n_name,'\\','/');
                end

                save(file_path_n_name,'curr_vids_path');
            end
            savepath pathdef.m;
            app.timer_vid_player = [];
            delete(app)
        end

        % Button pushed function: arrow_right_add_bboxes
        function arrow_right_add_bboxesButtonPushed(app, event)
            if app.video_has_been_loaded()
                app.copy_bboxes_to_next_fr = true;
                app.arrow_rightButtonPushed(true);
            end
        end

        % Value changing function: frameSpinner
        function frameSpinnerValueChanging(app, event)
            changingValue = event.Value;

        end

        % Button pushed function: deleteallbboxesinframeButton
        function deleteallbboxesinframeButtonPushed(app, event)
            if app.video_has_been_loaded()
                app.curr_vid_obj.delete_all_bboxes_in_selected_fr();
            end
        end

        % Button pushed function: deleteallbboxesinvideoButton
        function deleteallbboxesinvideoButtonPushed(app, event)
            if app.video_has_been_loaded()
                app.curr_vid_obj.delete_all_bboxes_in_video();
            end
        end

        % Button pushed function: arrow_left_add_bboxes
        function arrow_left_add_bboxesButtonPushed(app, event)
            if app.video_has_been_loaded()
                app.copy_bboxes_to_prev_fr = true;
                app.arrow_leftButtonPushed(true);
            end
        end

        % Value changed function: minmaxnormvideoCheckBox
        function minmaxnormvideoCheckBoxValueChanged(app, event)
            vid_norm = app.minmaxnormvideoCheckBox.Value;
            if vid_norm
                app.curr_vid_obj.norm_over_vid();
                app.curr_vid_obj.set_show_I_minmax(3);
            else
                app.curr_vid_obj.set_show_I_minmax(app.minmaxnormframeCheckBox.Value);
            end
        end

        % Button down function: App_uifigure
        function App_uifigureButtonDown(app, event)
            %             app.App_uifigure.KeyPressFcn
            app.App_uifigureKeyPress(event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.App_uifigure = uifigure('Visible', 'off');
            app.App_uifigure.Name = 'Holo Video Labeler';
            app.App_uifigure.CloseRequestFcn = createCallbackFcn(app, @App_uifigureCloseRequest, true);
            app.App_uifigure.ButtonDownFcn = createCallbackFcn(app, @App_uifigureButtonDown, true);
            app.App_uifigure.KeyPressFcn = createCallbackFcn(app, @App_uifigureKeyPress, true);
            app.ax_holofr = uiaxes(app.App_uifigure);
            app.ax_holofr.XTick = [];
            app.ax_holofr.XTickLabel = {'[ ]'};
            app.ax_holofr.YTick = [];
            app.ax_holofr.ButtonDownFcn = createCallbackFcn(app, @ax_holofrButtonDown, true);
            app.selectvideosButton = uibutton(app.App_uifigure, 'push');
            app.selectvideosButton.ButtonPushedFcn = createCallbackFcn(app, @selectvideosButtonPushed, true);
            app.selectvideosButton.Text = 'select video(s)';
            app.hologramvideosListBoxLabel = uilabel(app.App_uifigure);
            app.hologramvideosListBoxLabel.Text = 'hologram videos';
            app.hologramvideosListBox = uilistbox(app.App_uifigure);
            app.hologramvideosListBox.Items = {'', ''};
            app.hologramvideosListBox.ValueChangedFcn = createCallbackFcn(app, @hologramvideosListBoxValueChanged, true);
            app.hologramvideosListBox.Value = '';
            app.arrow_left = uibutton(app.App_uifigure, 'push');
            app.arrow_left.ButtonPushedFcn = createCallbackFcn(app, @arrow_leftButtonPushed, true);
            app.arrow_left.Text = '<';
            app.arrow_right = uibutton(app.App_uifigure, 'push');
            app.arrow_right.ButtonPushedFcn = createCallbackFcn(app, @arrow_rightButtonPushed, true);
            app.arrow_right.Text = '>';
            app.newlabel_button = uibutton(app.App_uifigure, 'state');
            app.newlabel_button.ValueChangedFcn = createCallbackFcn(app, @newlabel_buttonValueChanged, true);
            app.newlabel_button.Text = {'New BBox'; '(press N on '; 'the keyboard)'};
            app.frameSpinnerLabel = uilabel(app.App_uifigure);
            app.frameSpinnerLabel.Text = 'frame';
            app.frameSpinner = uispinner(app.App_uifigure);
            app.frameSpinner.ValueChangingFcn = createCallbackFcn(app, @frameSpinnerValueChanging, true);
            app.frameSpinner.Limits = [1 Inf];
            app.frameSpinner.ValueChangedFcn = createCallbackFcn(app, @frameSpinnerValueChanged, true);
            app.frameSpinner.Value = 1;
            app.videoplayerPanel = uipanel(app.App_uifigure);
            app.videoplayerPanel.Title = 'video player';
            app.play_button = uibutton(app.videoplayerPanel, 'push');
            app.play_button.ButtonPushedFcn = createCallbackFcn(app, @play_buttonButtonPushed, true);
            app.play_button.Text = '▶';
            app.stop_button = uibutton(app.videoplayerPanel, 'push');
            app.stop_button.ButtonPushedFcn = createCallbackFcn(app, @stop_buttonButtonPushed, true);
            app.stop_button.Text = '▇';
            app.framerateEditFieldLabel = uilabel(app.videoplayerPanel);
            app.framerateEditFieldLabel.Text = 'framerate';
            app.framerateEditField = uieditfield(app.videoplayerPanel, 'numeric');
            app.framerateEditField.ValueChangedFcn = createCallbackFcn(app, @framerateEditFieldValueChanged, true);
            app.minmaxnormframeCheckBox = uicheckbox(app.App_uifigure);
            app.minmaxnormframeCheckBox.ValueChangedFcn = createCallbackFcn(app, @minmaxnormframeCheckBoxValueChanged, true);
            app.minmaxnormframeCheckBox.Text = 'min-max norm frame';
            app.SuggestBBoxButton = uibutton(app.App_uifigure, 'push');
            app.SuggestBBoxButton.ButtonPushedFcn = createCallbackFcn(app, @suggestbboxesButtonPushed, true);
            app.SuggestBBoxButton.Text = 'Suggest BBox';
            app.suggestionresultTextAreaLabel = uilabel(app.App_uifigure);
            app.suggestionresultTextAreaLabel.Text = 'suggestion result';
            app.suggestionresultTextArea = uitextarea(app.App_uifigure);
            app.suggestionresultTextArea.ValueChangedFcn = createCallbackFcn(app, @suggestbboxesButtonPushed, true);
            app.suggestionresultTextArea.Value = {'summary of results for *suggest box* are shown here.'};
            app.NovideofoundLabel = uilabel(app.App_uifigure);
            app.NovideofoundLabel.Text = 'No video found.';
            app.deleteallbboxesinframeButton = uibutton(app.App_uifigure, 'push');
            app.deleteallbboxesinframeButton.ButtonPushedFcn = createCallbackFcn(app, @deleteallbboxesinframeButtonPushed, true);
            app.deleteallbboxesinframeButton.Text = {'delete all bboxes '; 'in frame'};
            app.deleteallbboxesinvideoButton = uibutton(app.App_uifigure, 'push');
            app.deleteallbboxesinvideoButton.ButtonPushedFcn = createCallbackFcn(app, @deleteallbboxesinvideoButtonPushed, true);
            app.deleteallbboxesinvideoButton.Text = {'delete all bboxes '; 'in video'};
            app.COPYALLBBOXESPanel = uipanel(app.App_uifigure);
            app.COPYALLBBOXESPanel.Title = 'COPY ALL BBOXES';
            app.arrow_right_add_bboxes = uibutton(app.COPYALLBBOXESPanel, 'push');
            app.arrow_right_add_bboxes.ButtonPushedFcn = createCallbackFcn(app, @arrow_right_add_bboxesButtonPushed, true);
            app.arrow_right_add_bboxes.Text = '>';
            app.arrow_left_add_bboxes = uibutton(app.App_uifigure, 'push');
            app.arrow_left_add_bboxes.ButtonPushedFcn = createCallbackFcn(app, @arrow_left_add_bboxesButtonPushed, true);
            app.arrow_left_add_bboxes.Enable = 'off';
            app.arrow_left_add_bboxes.Visible = 'off';
            app.arrow_left_add_bboxes.Text = '<';
            app.minmaxnormvideoCheckBox = uicheckbox(app.App_uifigure);
            app.minmaxnormvideoCheckBox.ValueChangedFcn = createCallbackFcn(app, @minmaxnormvideoCheckBoxValueChanged, true);
            app.minmaxnormvideoCheckBox.Enable = 'off';
            app.minmaxnormvideoCheckBox.Visible = 'off';
            app.minmaxnormvideoCheckBox.Text = 'min-max norm video';
            % Show the figure after all components are created
            app.App_uifigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ui_holo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.App_uifigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.App_uifigure)
        end
    end
end